classdef align_rois < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveButton                matlab.ui.control.Button
        OpenButton_2              matlab.ui.control.Button
        SavefolderEditField       matlab.ui.control.EditField
        SavefolderEditFieldLabel  matlab.ui.control.Label
        ShowROIsButton_2          matlab.ui.control.StateButton
        ShowROIsButton_1          matlab.ui.control.StateButton
        OpacityEditField          matlab.ui.control.NumericEditField
        OpacityEditFieldLabel     matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        AngleEditField            matlab.ui.control.NumericEditField
        AngleEditFieldLabel       matlab.ui.control.Label
        ScaleEditField            matlab.ui.control.NumericEditField
        ScaleEditFieldLabel       matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        AngleSlider               matlab.ui.control.Slider
        ScaleSlider               matlab.ui.control.Slider
        YSlider                   matlab.ui.control.Slider
        XSlider                   matlab.ui.control.Slider
        OpacitySlider             matlab.ui.control.Slider
        LoadButton_2              matlab.ui.control.Button
        LoadButton_1              matlab.ui.control.Button
        SessionDropDown_2         matlab.ui.control.DropDown
        Session2DropDownLabel     matlab.ui.control.Label
        SessionDropDown_1         matlab.ui.control.DropDown
        Session1refDropDownLabel  matlab.ui.control.Label
        OpenButton                matlab.ui.control.Button
        ROIfolderEditField        matlab.ui.control.EditField
        ROIfolderEditFieldLabel   matlab.ui.control.Label
        im                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N = 2;
        loaded_session = cell(1,2);
        datapath = '';
        savepath = '';
        Cns = cell(1,2);
        rois = cell(1,2);
        ROImasks = cell(1,2);
        hi = [];
        fov = [];
        opacity = [];
        x = [];
        y = [];
        angle = [];
        scale = [];
    end
    
    methods (Access = private)
        
        function load_session(app,n)
            app.loaded_session{n} = app.(['SessionDropDown_',num2str(n)]).Value;
            dload = load(fullfile(app.datapath,app.loaded_session{n}));
            % Main alignment image
            Cn = dload.Cn;
            Cn = Cn./max(Cn(:));
            Cn = uint16(Cn*255); 
            if n== 1
                map = gray(256);
            else
                map = copper(256);
            end
            app.Cns{n} = ind2rgb(Cn,map);

            % Mask cover all identified ROIs
            app.rois{n} = dload.rois;
            ROImask = zeros(size(app.rois{n}));
            for i = 1:size(app.rois{n},1)
                Rtmp = squeeze(app.rois{n}(i,:,:))>0;
                Rtmp = imerode(Rtmp,strel('sphere',3));
                ROImask(i,:,:) = Rtmp;
            end
            app.ROImasks{n} = squeeze(sum(ROImask,1)>0);

            [cols, rows] = meshgrid(1:size(Cn,1), 1:size(Cn,2));
            FoV= double((rows - size(Cn,1)/2).^2 + (cols - size(Cn,2)/2).^2 <= (size(Cn,2)/2).^2);
            app.fov = FoV;
        end

        function update_im(app,x,y,angle,scale)
%             opacity = app.OpacityEditField.Value;
            x = app.x;
            y = app.y;
            angle = app.angle;
            scale = app.scale;

            z = app.Cns{2};
            [h,w,~] = size(z);

            if app.ShowROIsButton_2.Value
                map = copper(256);
                z = zeros(size(z));
                zr = z(:,:,1); zr(app.ROImasks{2}) = map(end,1);
                zg = z(:,:,2); zg(app.ROImasks{2}) = map(end,2);
                zb = z(:,:,3); zb(app.ROImasks{2}) = map(end,3);
                z = cat(3,zr,zg,zb);
            end
            z = imrotate(z,angle,'bilinear','crop');
            if scale > 1 
                z = imresize(z,scale);
                [nh,nw,~] = size(z);
                z = z(1+floor(nh/2) - floor(h/2) : 1+floor(nh/2) + floor(h/2),...
                    1+floor(nw/2) - floor(w/2) : 1+floor(nw/2) + floor(w/2),:);
            elseif(scale < 1)
                z = imresize(z,scale);
                [nh,nw,~] = size(z);
                nz = zeros(h,w,3);
                try
                nz(1+floor(h/2) - floor(nh/2) : 1+floor(h/2) + floor(nh/2),...
                    1+floor(w/2) - floor(nw/2) : 1+floor(w/2) + floor(nw/2),:) = z;
                catch
                 nz(1+floor(h/2) - floor(nh/2) : floor(h/2) + floor(nh/2),...
                    1+floor(w/2) - floor(nw/2) : floor(w/2) + floor(nw/2),:) = z;
                end
                z = nz;
            end
            z = imtranslate(z,[x,y]);
            
            zref = app.Cns{1};
            if app.ShowROIsButton_1.Value
                    map = gray(256);
                    zref = zeros(size(zref));
                    zr = zref(:,:,1); zr(app.ROImasks{1}) = map(end,1);
                    zg = zref(:,:,2); zg(app.ROImasks{1}) = map(end,2);
                    zb = zref(:,:,3); zb(app.ROImasks{1}) = map(end,3);
                    zref = cat(3,zr,zg,zb);
            end

            imagesc(app.im,zref);
            hi= imagesc(app.im,z);
                
            mask = get_mask(app);
            mask(z(:,:,1)==-1) = 0;
            set(hi,'AlphaData',mask);
            axis(app.im,'tight');    

        end

        function mask = get_mask(app)
            FOV = app.fov;
            [h,w] = size(FOV);

            mask = ones(size(app.Cns{1},1),size(app.Cns{1},2)) + 0.01*app.OpacitySlider.Value*FOV - 1;
            
            scale = app.ScaleEditField.Value;
            if scale > 1 
                mask = imresize(mask,scale);
                [nh,nw,~] = size(mask);
                mask = mask(1+floor(nh/2) - floor(h/2) : 1+floor(nh/2) + floor(h/2),...
                    1+floor(nw/2) - floor(w/2) : 1+floor(nw/2) + floor(w/2));
            elseif(scale < 1)
                mask = imresize(mask,scale);
                [nh,nw,~] = size(mask);
                nmask = zeros(h,w);
                try
                    nmask(1+floor(h/2) - floor(nh/2) : 1+floor(h/2) + floor(nh/2),...
                    1+floor(w/2) - floor(nw/2) : 1+floor(w/2) + floor(nw/2)) = mask;
                catch
                    nmask(1+floor(h/2) - floor(nh/2) :floor(h/2) + floor(nh/2),...
                    1+floor(w/2) - floor(nw/2) : floor(w/2) + floor(nw/2)) = mask;
                end
                mask = nmask;
            end                       
            mask = imtranslate(mask,[app.XSlider.Value,app.YSlider.Value]);            
            %mask = imrotate(mask,app.AngleSlider.Value,'bilinear','crop');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.opacity = 50;
            app.x = 0;
            app.y = 0;
            app.angle =0;
            app.scale = 1;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            currdir = uigetdir;
            app.datapath = currdir;
            app.ROIfolderEditField.Value = currdir;
            files = dir(fullfile(currdir,'*.mat'));
            filenames = {};
            for i = 1:numel(files)
                filenames{i} = files(i).name;
            end
            app.SessionDropDown_1.Items = filenames;
            app.SessionDropDown_2.Items = filenames;
        end

        % Button pushed function: LoadButton_1
        function LoadButton_1Pushed(app, event)
            load_session(app,1);
            hold(app.im,'off');
            imagesc(app.im,app.Cns{1});
            hold(app.im,'on');
            axis(app.im,'tight');

        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            load_session(app,2);
            hold(app.im,'off');
            imagesc(app.im,app.Cns{2});
            hold(app.im,'on');
            axis(app.im,'tight');

        end

        % Value changed function: OpacityEditField
        function OpacityEditFieldValueChanged(app, event)
            value = round(app.OpacityEditField.Value);
            app.OpacityEditField.Value = value;
            app.OpacitySlider.Value = value;
            app.opacity = value;
            update_im(app)
        end

        % Value changing function: OpacitySlider
        function OpacitySliderValueChanging(app, event)
            value = round(event.Value);
            app.OpacityEditField.Value = value;
            app.OpacitySlider.Value = value;
            app.opacity = value;
            update_im(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = round(app.XEditField.Value);
            app.XSlider.Value = value;
            app.XEditField.Value = value;
            app.x = value;
            update_im(app)            
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.XSlider.Value = changingValue;
            app.XEditField.Value = changingValue;
            app.x = changingValue;
            update_im(app)
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = round(app.YEditField.Value);
            app.YSlider.Value = value;
            app.YEditField.Value = value;   
            app.y = value;
            update_im(app);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.YSlider.Value = changingValue;
            app.YEditField.Value = changingValue;
            app.y = changingValue;
            update_im(app);
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = round(app.AngleEditField.Value*2)/2;
            app.AngleSlider.Value = value;
            app.angle = value;
            update_im(app);
        end

        % Value changing function: AngleSlider
        function AngleSliderValueChanging(app, event)
            changingValue = round(event.Value*2)/2;
            app.AngleEditField.Value = changingValue;
            app.angle = changingValue;
            update_im(app);
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            value = round(app.ScaleEditField.Value*100)/100;
            app.ScaleSlider.Value = value;
            app.scale = value;
            update_im(app);
        end

        % Value changing function: ScaleSlider
        function ScaleSliderValueChanging(app, event)
            changingValue = round(event.Value*100)/100;
            app.ScaleEditField.Value = changingValue;
            app.scale = changingValue;
            update_im(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.opacity = 50;
            app.OpacityEditField.Value = 50;
            app.OpacitySlider = 50;

            app.x = 0;
            app.XEditField.Value = 0;
            app.XSlider.Value = 0;

            app.y = 0;
            app.YEditField.Value = 0;
            app.YSlider = 0 ;

            app.angle =0;
            app.AngleEditField.Value = 0;
            app.AngleSlider = 0;

            app.scale = 1;
            app.ScaleEditField = 1;
            app.ScaleSlider.Value = 1;

        end

        % Value changed function: ROIfolderEditField
        function ROIfolderEditFieldValueChanged(app, event)
            currdir = app.ROIfolderEditField.Value;
            app.datapath = currdir;
            app.ROIfolderEditField.Value = currdir;
            files = dir(fullfile(currdir,'*.mat'));
            filenames = {};
            for i = 1:numel(files)
                filenames{i} = files(i).name;
            end
            app.SessionDropDown_1.Items = filenames;
            app.SessionDropDown_2.Items = filenames;
        end

        % Value changed function: ShowROIsButton_1
        function ShowROIsButton_1ValueChanged(app, event)
            value = app.ShowROIsButton_1.Value;
            update_im(app);
        end

        % Value changed function: ShowROIsButton_2
        function ShowROIsButton_2ValueChanged(app, event)
            value = app.ShowROIsButton_2.Value;
            update_im(app);
        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            currdir = uigetdir;
            app.savepath = currdir;
            app.SavefolderEditField.Value = currdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            input_path = app.ROIfolderEditField.Value;
            output_path = app.SavefolderEditField.Value;
            
            reference_file = fullfile(input_path,app.SessionDropDown_1.Value);
            aligned_file = fullfile(input_path,app.SessionDropDown_2.Value);

            
            roiload_2 = load(aligned_file);
            
            roi_align = roiload_2.rois;
            Cn_align = roiload_2.Cn;
            x = app.x;
            y = app.y;
            angle = app.angle;
            scale = app.scale;

            tmp = imrotate(Cn_align,angle,'bilinear','crop');
            if scale > 1 
                    tmp = imresize(tmp,scale);
                    [nh,nw] = size(tmp);
                    tmp = tmp(1+floor(nh/2) - floor(h/2) : 1+floor(nh/2) + floor(h/2),...
                        1+floor(nw/2) - floor(w/2) : 1+floor(nw/2) + floor(w/2),:);
                elseif(scale < 1)
                    tmp = imresize(tmp,scale);
                    [nh,nw,~] = size(tmp);
                    nz = zeros(h,w);
                    try
                    nz(1+floor(h/2) - floor(nh/2) : 1+floor(h/2) + floor(nh/2),...
                        1+floor(w/2) - floor(nw/2) : 1+floor(w/2) + floor(nw/2)) = tmp;
                    catch
                     nz(1+floor(h/2) - floor(nh/2) : floor(h/2) + floor(nh/2),...
                        1+floor(w/2) - floor(nw/2) : floor(w/2) + floor(nw/2)) = tmp;
                    end
                    tmp = nz;
            end
            tmp = imtranslate(tmp,[x,y]);
            Cn_align = tmp;


            for i_c = 1:size(roi_align,1)
                tmp = squeeze(roi_align(i_c,:,:));
                [h,w] = size(tmp);
                
                tmp = imrotate(tmp,angle,'bilinear','crop');
                if scale > 1 
                    tmp = imresize(tmp,scale);
                    [nh,nw] = size(tmp);
                    tmp = tmp(1+floor(nh/2) - floor(h/2) : 1+floor(nh/2) + floor(h/2),...
                        1+floor(nw/2) - floor(w/2) : 1+floor(nw/2) + floor(w/2),:);
                elseif(scale < 1)
                    tmp = imresize(tmp,scale);
                    [nh,nw,~] = size(tmp);
                    nz = zeros(h,w);
                    try
                    nz(1+floor(h/2) - floor(nh/2) : 1+floor(h/2) + floor(nh/2),...
                        1+floor(w/2) - floor(nw/2) : 1+floor(w/2) + floor(nw/2)) = tmp;
                    catch
                     nz(1+floor(h/2) - floor(nh/2) : floor(h/2) + floor(nh/2),...
                        1+floor(w/2) - floor(nw/2) : floor(w/2) + floor(nw/2)) = tmp;
                    end
                    tmp = nz;
                end
                tmp_t = imtranslate(tmp,[x,y]);
                shift_incr = 0.01;
                   while sum(tmp_t(:)>0) < 10 % if roi gets shifted out of frame,
                       % try shifting a bit less until roi is on edge of FOV
                       tmp_t = imtranslate(tmp,[x*(1-shift_incr),y*(1-shift_incr)]);
                       shift_incr = shift_incr +0.01;
                       if shift_incr >=1;
                           break
                       end
                   end
                tmp = tmp_t;
                roi_align(i_c,:,:) = tmp;
            end
            Cn = Cn_align;
            rois = roi_align;
            save(fullfile(output_path,app.SessionDropDown_2.Value),'Cn','rois')     

            roiload_1 = load(reference_file);
            Cn = roiload_1.Cn;
            roi = roiload_1.rois;
            save(fullfile(output_path,app.SessionDropDown_1.Value),'Cn','rois')
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.im = uiaxes(app.UIFigure);
            app.im.XTick = [];
            app.im.YTick = [];
            app.ROIfolderEditFieldLabel = uilabel(app.UIFigure);
            app.ROIfolderEditFieldLabel.Text = 'ROI folder';
            app.ROIfolderEditField = uieditfield(app.UIFigure, 'text');
            app.ROIfolderEditField.ValueChangedFcn = createCallbackFcn(app, @ROIfolderEditFieldValueChanged, true);
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Session1refDropDownLabel = uilabel(app.UIFigure);
            app.Session1refDropDownLabel.Text = 'Session 1 (ref)';
            app.SessionDropDown_1 = uidropdown(app.UIFigure);
            app.Session2DropDownLabel = uilabel(app.UIFigure);
            app.Session2DropDownLabel.Text = 'Session 2';
            app.SessionDropDown_2 = uidropdown(app.UIFigure);
            app.LoadButton_1 = uibutton(app.UIFigure, 'push');
            app.LoadButton_1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_1Pushed, true);
            app.LoadButton_1.Text = 'Load';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.OpacitySlider = uislider(app.UIFigure);
            app.OpacitySlider.MajorTicks = [];
            app.OpacitySlider.ValueChangingFcn = createCallbackFcn(app, @OpacitySliderValueChanging, true);
            app.OpacitySlider.MinorTicks = [];
            app.OpacitySlider.Value = 50;
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-65 65];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.MinorTicks = [];
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-85 85];
            app.YSlider.MajorTicks = [];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.MinorTicks = [];
            app.ScaleSlider = uislider(app.UIFigure);
            app.ScaleSlider.Limits = [0.5 1.5];
            app.ScaleSlider.MajorTicks = [];
            app.ScaleSlider.ValueChangingFcn = createCallbackFcn(app, @ScaleSliderValueChanging, true);
            app.ScaleSlider.MinorTicks = [];
            app.ScaleSlider.Value = 1;
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [-180 180];
            app.AngleSlider.MajorTicks = [];
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @AngleSliderValueChanging, true);
            app.AngleSlider.MinorTicks = [];
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [-65 65];
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField.Limits = [0.5 1.5];
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = 1;
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.Limits = [-180 180];
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Limits = [-85 85];
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.OpacityEditFieldLabel = uilabel(app.UIFigure);
            app.OpacityEditFieldLabel.Text = 'Opacity';
            app.OpacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.OpacityEditField.Limits = [0 100];
            app.OpacityEditField.ValueChangedFcn = createCallbackFcn(app, @OpacityEditFieldValueChanged, true);
            app.OpacityEditField.Value = 50;
            app.ShowROIsButton_1 = uibutton(app.UIFigure, 'state');
            app.ShowROIsButton_1.ValueChangedFcn = createCallbackFcn(app, @ShowROIsButton_1ValueChanged, true);
            app.ShowROIsButton_1.Text = 'Show ROIs';
            app.ShowROIsButton_2 = uibutton(app.UIFigure, 'state');
            app.ShowROIsButton_2.ValueChangedFcn = createCallbackFcn(app, @ShowROIsButton_2ValueChanged, true);
            app.ShowROIsButton_2.Text = 'Show ROIs';
            app.SavefolderEditFieldLabel = uilabel(app.UIFigure);
            app.SavefolderEditFieldLabel.Text = 'Save folder';
            app.SavefolderEditField = uieditfield(app.UIFigure, 'text');
            app.OpenButton_2 = uibutton(app.UIFigure, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = align_rois

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end